classdef FijiIDClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeepLearningFijiIDClassifierUIFigure  matlab.ui.Figure
        ControlsPanel                matlab.ui.container.Panel
        ExitApplicationButton        matlab.ui.control.Button
        LoadImageButton              matlab.ui.control.Button
        UploadedImagePanel           matlab.ui.container.Panel
        PredictResultLabel_2         matlab.ui.control.Label
        IDTypeLabel                  matlab.ui.control.Label
        UIAxesUploadedImage          matlab.ui.control.UIAxes
        FacialDetectionResultsPanel  matlab.ui.container.Panel
        UIAxesFaces                  matlab.ui.control.UIAxes
        UIAxesOriginal               matlab.ui.control.UIAxes
        ClassificationResultsPanel   matlab.ui.container.Panel
        PredictResultLabel           matlab.ui.control.Label
        PredictedLabel               matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        UIAxesClassification         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Step 1: Read the Image
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            load('TrainedOverSampledFijiIDCardClassifier.mat', 'netTransfer');


            % Get the input size of the network
            inputSize = netTransfer.Layers(1).InputSize;
            
            % Resize the image to match the input size of the network
            imgResized = imresize(img, inputSize(1:2));
            
            % Classify the image
            [YPred, scores] = classify(netTransfer, imgResized);
            
            % Display the image with the predicted label as the title
            %disp(['Predicted label: ', char(YPred)]);
           % figure;
           % imshow(img);
            image(img, 'Parent', app.UIAxesUploadedImage);
            app.PredictedLabel.Visible = "on";
            app.IDTypeLabel.Visible = "on";
            app.PredictResultLabel.Visible = "on";
            app.PredictResultLabel.Text = regexprep(char(YPred), '([A-Z])', ' $1');
            app.PredictResultLabel_2.Visible = "on";
            app.PredictResultLabel_2.Text = regexprep(char(YPred), '([A-Z])', ' $1');
            if char(YPred) == "DriversLicense"
               app.PredictResultLabel.FontColor = "1.00,0.07,0.65";
               app.PredictResultLabel_2.FontColor = "1.00,0.07,0.65";
            end

            if char(YPred) == "Passport"
               app.PredictResultLabel.FontColor = "0.00,0.00,1.00";
               app.PredictResultLabel_2.FontColor = "0.00,0.00,1.00";
            end
            
            if char(YPred) == "JointFNPFTIN"
               app.PredictResultLabel.FontColor = "0.64,0.08,0.18";
               app.PredictResultLabel_2.FontColor = "0.64,0.08,0.18";
                app.PredictResultLabel_2.Text = "Joint FNPF/TIN Card";
                app.PredictResultLabel.Text = "Joint FNPF/TIN Card";
            end

            if char(YPred) == "VoterRegistration"
               app.PredictResultLabel.FontColor = "0.47,0.67,0.19";
               app.PredictResultLabel_2.FontColor = "0.47,0.67,0.19";
            end

            %title(app.UIAxesUploadedImage, ['Predicted ID Type: ', char(YPred)]);
            
            disp('Class probabilities:');
            scoresTable = table(netTransfer.Layers(end).Classes, transpose(scores), 'VariableNames', {'Class', 'Probability'});
             app.UITable.Data = scoresTable;
            % disp(scoresTable);

            bar(app.UIAxesClassification,scores);
           % title(app.UIAxesClassification,['Predicted: ', char(YPred)]);
            xlabel(app.UIAxesClassification,'Class');
            ylabel(app.UIAxesClassification,'Probability');
            xticklabels(app.UIAxesClassification,netTransfer.Layers(end).Classes);
            xtickangle(app.UIAxesClassification,45);
            app.UIAxesClassification.Visible = "on";
             % Step 2: Define possible orientations
            rotations = [0, 90, 180, 270];
            maxFaces = 0;
            bestArea = 0;
            bestFaceArea = 0;
            faceArea = 0;
            bestOrientation = 0;
            bestBboxes = [];
            bestImg = img;

             % Step 3: Detect Faces in each orientation
            for i = 1:length(rotations)
                rotatedImg = imrotate(img, rotations(i));
                faceDetector = vision.CascadeObjectDetector(); % Create a detector object
                bboxes = step(faceDetector, rotatedImg); % Detect faces

                % Check if this orientation detects more faces
                numFaces = size(bboxes, 1);
                bestFaceArea = 0;
                for x = 1:numFaces
                    width = bboxes(x, 3);
                    height = bboxes(x, 4);
                    faceArea = width * height;
                    if (bestFaceArea < faceArea)
                       bestFaceArea = faceArea;
                    end
                    
                end

                if bestFaceArea > bestArea%numFaces > maxFaces
                    maxFaces = numFaces;
                    bestOrientation = rotations(i);
                    bestBboxes = bboxes;
                    bestImg = rotatedImg;
                    bestArea=bestFaceArea;
                end
            end

             % Step 4: Extract Faces from the best orientation
            faceImages = cell(maxFaces, 1); % Cell array to hold extracted face images

            for i = 1:maxFaces
                % Extract the bounding box for the current face
                x = bestBboxes(i, 1);
                y = bestBboxes(i, 2);
                width = bestBboxes(i, 3);
                height = bestBboxes(i, 4);

                % Crop the face from the image
                faceImages{i} = imcrop(bestImg, [x, y, width, height]);
            end

             % Display the best-oriented image with bounding boxes
            image(bestImg, 'Parent', app.UIAxesOriginal);
            hold(app.UIAxesOriginal, 'on');
            for i = 1:maxFaces
                rectangle(app.UIAxesOriginal, 'Position', bestBboxes(i, :), 'EdgeColor', 'r', 'LineWidth', 2);
            end
            title(app.UIAxesOriginal, ['Detected Faces in Best Orientation (', num2str(bestOrientation), ' degrees)']);
            hold(app.UIAxesOriginal, 'off');

            % Display the extracted faces
            cla(app.UIAxesFaces);
            if maxFaces > 0
                % numCols = min(maxFaces, 5); % Number of columns in the subplot grid (up to 5)
                % numRows = ceil(maxFaces / numCols); % Number of rows in the subplot grid
                % for i = 1:maxFaces
                %     subplot(numRows, numCols, i, 'Parent', app.UIAxesFaces);
                %     imshow(faceImages{i}, 'InitialMagnification', 'fit'); % Adjust magnification to fit the subplot
                %     axis on; % Turn on axis to see the boundaries
                %     title(['Face ', num2str(i)]);
                % end
               % subplot(numRows, numCols, maxFaces, 'Parent', app.UIAxesFaces);
                image(faceImages{maxFaces}, 'Parent', app.UIAxesFaces); % Adjust magnification to fit the subplot
                title(app.UIAxesFaces, 'Detected Face');
            end
        end

        % Button pushed function: ExitApplicationButton
        function ExitApplicationButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeepLearningFijiIDClassifierUIFigure = uifigure('Visible', 'off');
            app.DeepLearningFijiIDClassifierUIFigure.Name = 'Deep Learning - Fiji ID Classifier';
            app.ClassificationResultsPanel = uipanel(app.DeepLearningFijiIDClassifierUIFigure);
            app.ClassificationResultsPanel.Title = 'Classification Results';
            app.UIAxesClassification = uiaxes(app.ClassificationResultsPanel);
            xlabel(app.UIAxesClassification, 'X')
            ylabel(app.UIAxesClassification, 'Y')
            zlabel(app.UIAxesClassification, 'Z')
            app.UIAxesClassification.Visible = 'off';
            app.UITable = uitable(app.ClassificationResultsPanel);
            app.UITable.ColumnName = {'Class'; 'Probability'};
            app.UITable.RowName = {};
            app.PredictedLabel = uilabel(app.ClassificationResultsPanel);
            app.PredictedLabel.Visible = 'off';
            app.PredictedLabel.Text = 'Predicted: ';
            app.PredictResultLabel = uilabel(app.ClassificationResultsPanel);
            app.PredictResultLabel.Visible = 'off';
            app.PredictResultLabel.Text = 'DriversLicense';
            app.FacialDetectionResultsPanel = uipanel(app.DeepLearningFijiIDClassifierUIFigure);
            app.FacialDetectionResultsPanel.Title = 'Facial Detection Results';
            app.UIAxesOriginal = uiaxes(app.FacialDetectionResultsPanel);
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesFaces = uiaxes(app.FacialDetectionResultsPanel);
            app.UIAxesFaces.XTick = [];
            app.UIAxesFaces.YTick = [];
            app.UploadedImagePanel = uipanel(app.DeepLearningFijiIDClassifierUIFigure);
            app.UploadedImagePanel.Title = 'Uploaded Image';
            app.UIAxesUploadedImage = uiaxes(app.UploadedImagePanel);
            app.UIAxesUploadedImage.XTick = [];
            app.UIAxesUploadedImage.YTick = [];
            app.IDTypeLabel = uilabel(app.UploadedImagePanel);
            app.IDTypeLabel.Visible = 'off';
            app.IDTypeLabel.Text = 'ID Type: ';
            app.PredictResultLabel_2 = uilabel(app.UploadedImagePanel);
            app.PredictResultLabel_2.Visible = 'off';
            app.PredictResultLabel_2.Text = 'DriversLicense';
            app.ControlsPanel = uipanel(app.DeepLearningFijiIDClassifierUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.LoadImageButton = uibutton(app.ControlsPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ExitApplicationButton = uibutton(app.ControlsPanel, 'push');
            app.ExitApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @ExitApplicationButtonPushed, true);
            app.ExitApplicationButton.Text = 'Exit Application';
            % Show the figure after all components are created
            app.DeepLearningFijiIDClassifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FijiIDClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeepLearningFijiIDClassifierUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeepLearningFijiIDClassifierUIFigure)
        end
    end
end